classdef data_acquisition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        StopDisplayButton            matlab.ui.control.StateButton
        StartdisplayButton           matlab.ui.control.Button
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Tab2                         matlab.ui.container.Tab
        SaveButton                   matlab.ui.control.Button
        SubjectNameEditField         matlab.ui.control.EditField
        SubjectNameEditFieldLabel    matlab.ui.control.Label
        RDeviationMVCEditField       matlab.ui.control.NumericEditField
        RDeviationMVCEditFieldLabel  matlab.ui.control.Label
        UDeviationMVCEditField       matlab.ui.control.NumericEditField
        UDeviationMVCEditFieldLabel  matlab.ui.control.Label
        ExtenMVCEditField_2          matlab.ui.control.NumericEditField
        ExtensionMVCLabel            matlab.ui.control.Label
        FlexionMVCEditField          matlab.ui.control.NumericEditField
        FlexionMVCEditFieldLabel     matlab.ui.control.Label
        MaximumValuntaryContractionGauge  matlab.ui.control.Gauge
        MaximumValuntaryContractionGaugeLabel  matlab.ui.control.Label
        HandGestureDropDown          matlab.ui.control.DropDown
        HandGestureDropDownLabel     matlab.ui.control.Label
        EstimateMVCButton            matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        Tab3                         matlab.ui.container.Tab
        StopRecordingButton          matlab.ui.control.Button
        StartRecordingButton         matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function  plotUnitCircle(app)
            th = 0 : pi/50:2*pi;
            xunit = 1 * cos(th) + 0;
            yunit = 1 * sin(th) + 0;
            hold(app.UIAxes2,"on");
            plot(app.UIAxes2,xunit,yunit);
            % xLine_marker = linspace(-1,1,5);
            % plot(app.UIAxes2,xLine_marker,zeros(5),"Color",'k',"LineWidth",2);
            % plot(app.UIAxes2,zeros(5),xLine_marker,"Color",'k',"LineWidth",2);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HandGestureDropDown
        function HandGestureDropDownValueChanged(app, event)
            switch app.HandGestureDropDown.Value
                case 'Flexion'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\flexion.jpg";
                case 'Extension'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\extention.jpg";
                case 'Ulnar Deviation'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\ulnar_deviation.jpg";
                case 'Radial Deviation'
                    app.Image.ImageSource = "\Users\lab-admin\Desktop\Rebecca\radial_deviation.jpg";
            end


        end

        % Image clicked function: Image
        function ImageClicked(app, event)

        end

        % Button pushed function: EstimateMVCButton
        function EstimateMVCButtonPushed(app, event)
            name = app.SubjectNameEditField.Value;
            folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name);
            Fs = 200; %Hz
            i = 0;
            maxValue = 0;
            mm = MyoMex();
            m1 = mm.myoData();
            pause(0.3);
            for i = 1:50
                e = m1.emg_log;
                sig = abs(e(end-39:end,:)); %200 ms time window just like the whole system
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(sig,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                emgMvc= 100 * mean(meanFeatures);
                app.MaximumValuntaryContractionGauge.Value = emgMvc;
                if emgMvc > maxValue
                    maxValue = emgMvc;
                end
                switch app.HandGestureDropDown.Value
                    case 'Flexion'
                        app.FlexionMVCEditField.Value = maxValue;
                    case 'Extension'
                        app.ExtenMVCEditField_2.Value = maxValue;
                    case 'Ulnar Deviation'
                        app.UDeviationMVCEditField.Value = maxValue;
                    case 'Radial Deviation'
                        app.RDeviationMVCEditField.Value = maxValue;
                end
                pause(0.2);
            end
            app.Tab
            e1 = e(end-2199:end,1);
            e2 = e(end-2199:end,2);
            e3 = e(end-2199:end,3);
            e4 = e(end-2199:end,4);
            e5 = e(end-2199:end,5);
            e6 = e(end-2199:end,6);
            e7 = e(end-2199:end,7);
            e8 = e(end-2199:end,8);
            plot(app.UIAxes,e1);
            plot(app.UIAxes_2,e2);
            plot(app.UIAxes_3,e3);
            plot(app.UIAxes_4,e4);
            plot(app.UIAxes_5,e5);
            plot(app.UIAxes_6,e6);
            plot(app.UIAxes_7,e7);
            plot(app.UIAxes_8,e8);
            mm.delete;
            clear('mm');
            if app.SaveButton.ButtonPushedFcn
                gesture = ["Flexion", "Extention","U. Deviation", "R. Deviation"];
                values = [app.FlexionMVCEditField.Value, app.ExtenMVCEditField_2.Value, app.UDeviationMVCEditField.Value, app.RDeviationMVCEditField.Value];
                MVCTable = table(values,'RowNames',gesture);
                fileName = fullfile(folder_name + "MVC_info");
                save(folder_name,"MVCTable");
            end
        end

        % Button pushed function: StartdisplayButton
        function StartdisplayButtonPushed(app, event)
            
            mm = MyoMex();
            m1 = mm.myoData();

            pause(5);
            while ~app.StopDisplayButton.Value
                e = m1.emg_log;
                e1 = e(end-599:end,1);
                e2 = e(end-599:end,2);
                e3 = e(end-599:end,3);
                e4 = e(end-599:end,4);
                e5 = e(end-599:end,5);
                e6 = e(end-599:end,6);
                e7 = e(end-599:end,7);
                e8 = e(end-599:end,8);
                plot(app.UIAxes,e1);drawnow
                plot(app.UIAxes_2,e2);drawnow
                plot(app.UIAxes_3,e3);drawnow
                plot(app.UIAxes_4,e4);drawnow
                plot(app.UIAxes_5,e5);drawnow
                plot(app.UIAxes_6,e6);drawnow
                plot(app.UIAxes_7,e7);drawnow
                plot(app.UIAxes_8,e8);drawnow
            end
            if app.StopDisplayButton.Value
                mm.delete;
                clear('mm');
            end
        end

        % Value changed function: StopDisplayButton
        function StopDisplayButtonValueChanged(app, event)
      
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.SubjectNameEditField.Value
                name = app.SubjectNameEditField.Value;
                folder_name = fullfile("\Users\lab-admin\Desktop\Rebecca\" + name);
                gesture = ["Flexion"; "Extention";"U. Deviation"; "R. Deviation"];
                values = [app.FlexionMVCEditField.Value; app.ExtenMVCEditField_2.Value; app.UDeviationMVCEditField.Value; app.RDeviationMVCEditField.Value];
                MVCTable = table(values,'RowNames',gesture);
                if ~exist(folder_name)
                    mkdir(folder_name);
                end
                fileName = fullfile(folder_name + '\' + "MVC_info.mat");
                save(fileName,"MVCTable");
            end
        end

        % Callback function
        function UserIntentionButtonGroupSelectionChanged(app, event)
            selectedButton = app.UserIntentionButtonGroup.SelectedObject;
           switch selectedButton
               case app.Button_3
                   app.Gauge.Visible = "on"
                   app.Gauge_2.Visible = "off"
               case app.Button_4
                   app.Gauge.Visible = "off"
                   app.Gauge_2.Visible = "on"
           end
        end

        % Callback function
        function HandGestureDropDown_2ValueChanged(app, event)
            value = app.HandGestureDropDown_2.Value;
            switch value
                case 'Flexion'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\flexion.jpg";
                case 'Extension'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\extention.jpg";
                case 'Ulnar Deviation'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\ulnar_deviation.jpg";
                case 'Radial Deviation'
                    app.Image_2.ImageSource = "\Users\lab-admin\Desktop\Rebecca\radial_deviation.jpg";
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.plotUnitCircle();                  
            mm = MyoMex();
            m1 = mm.myoData();
            pause(0.3);
            for i = 1:1000000
                e = m1.emg_log;
                Fs = 200;
                sig = abs(e(end-39:end,:)); %200 ms time window just like the whole system
                meanF = signalTimeFeatureExtractor("Mean", true, 'SampleRate', Fs);
                meanFDS = arrayDatastore(sig,"IterationDimension",2);
                meanFDS = transform(meanFDS,@(x)meanF.extract(x{:}));
                meanFeatures = readall(meanFDS,"UseParallel",true);
                MVC= 0.22;
                action = mean(meanFeatures);
                gaol = [1,0];
                scatter(app.UIAxes2,action,0,'blue');
                scatter(app.UIAxes2,1,0,"+");
                % emgMvc= 100 * mean(meanFeatures);
                % switch app.HandGestureDropDown_2.Value
                %     case 'Flexion'
                %         mvc = app.FlexionMVCEditField.Value;
                %         intentionValue = emgMvc/mvc*100;
                %     case 'Extension'
                %         mvc = app.ExtenMVCEditField_2.Value;
                %         intentionValue = emgMvc/mvc*100;
                %     case 'Ulnar Deviation'
                %         mvc = app.UDeviationMVCEditField.Value;
                %         intentionValue = emgMvc/mvc*100;
                %     case 'Radial Deviation'
                %         mvc = app.RDeviationMVCEditField.Value;
                %         intentionValue = emgMvc/mvc*100;
                % end


            end
            mm.delete;
            clear('mm');
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            app.plotUnitCircle(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Ch.1')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-1 1];
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, 'Ch.2')
            xlabel(app.UIAxes_2, 'Sample')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [-1 1];
            app.UIAxes_3 = uiaxes(app.Tab);
            title(app.UIAxes_3, 'Ch.3')
            xlabel(app.UIAxes_3, 'Sample')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.YLim = [-1 1];
            app.UIAxes_4 = uiaxes(app.Tab);
            title(app.UIAxes_4, 'Ch.4')
            xlabel(app.UIAxes_4, 'Sample')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.YLim = [-1 1];
            app.UIAxes_5 = uiaxes(app.Tab);
            title(app.UIAxes_5, 'Ch.5')
            xlabel(app.UIAxes_5, 'Sample')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.YLim = [-1 1];
            app.UIAxes_6 = uiaxes(app.Tab);
            title(app.UIAxes_6, 'Ch.6')
            xlabel(app.UIAxes_6, 'Sample')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.YLim = [-1 1];
            app.UIAxes_7 = uiaxes(app.Tab);
            title(app.UIAxes_7, 'Ch.7')
            xlabel(app.UIAxes_7, 'Sample')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.YLim = [-1 1];
            app.UIAxes_8 = uiaxes(app.Tab);
            title(app.UIAxes_8, 'Ch.8')
            xlabel(app.UIAxes_8, 'Sample')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.YLim = [-1 1];
            app.StartdisplayButton = uibutton(app.Tab, 'push');
            app.StartdisplayButton.ButtonPushedFcn = createCallbackFcn(app, @StartdisplayButtonPushed, true);
            app.StartdisplayButton.Text = 'Start display';
            app.StopDisplayButton = uibutton(app.Tab, 'state');
            app.StopDisplayButton.ValueChangedFcn = createCallbackFcn(app, @StopDisplayButtonValueChanged, true);
            app.StopDisplayButton.Text = 'Stop Display';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Image = uiimage(app.Tab2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'flexion.jpg');
            app.EstimateMVCButton = uibutton(app.Tab2, 'push');
            app.EstimateMVCButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateMVCButtonPushed, true);
            app.EstimateMVCButton.Text = 'Estimate MVC';
            app.HandGestureDropDownLabel = uilabel(app.Tab2);
            app.HandGestureDropDownLabel.Text = 'Hand Gesture';
            app.HandGestureDropDown = uidropdown(app.Tab2);
            app.HandGestureDropDown.Items = {'Flexion', 'Extension', 'Ulnar Deviation', 'Radial Deviation'};
            app.HandGestureDropDown.ValueChangedFcn = createCallbackFcn(app, @HandGestureDropDownValueChanged, true);
            app.HandGestureDropDown.Value = 'Flexion';
            app.MaximumValuntaryContractionGaugeLabel = uilabel(app.Tab2);
            app.MaximumValuntaryContractionGaugeLabel.Text = 'Maximum Valuntary Contraction';
            app.MaximumValuntaryContractionGauge = uigauge(app.Tab2, 'circular');
            app.FlexionMVCEditFieldLabel = uilabel(app.Tab2);
            app.FlexionMVCEditFieldLabel.Text = 'Flexion MVC';
            app.FlexionMVCEditField = uieditfield(app.Tab2, 'numeric');
            app.FlexionMVCEditField.Value = 32;
            app.ExtensionMVCLabel = uilabel(app.Tab2);
            app.ExtensionMVCLabel.Text = 'Extension MVC';
            app.ExtenMVCEditField_2 = uieditfield(app.Tab2, 'numeric');
            app.ExtenMVCEditField_2.Value = 32;
            app.UDeviationMVCEditFieldLabel = uilabel(app.Tab2);
            app.UDeviationMVCEditFieldLabel.Text = 'U. Deviation MVC';
            app.UDeviationMVCEditField = uieditfield(app.Tab2, 'numeric');
            app.UDeviationMVCEditField.Value = 20;
            app.RDeviationMVCEditFieldLabel = uilabel(app.Tab2);
            app.RDeviationMVCEditFieldLabel.Text = 'R. Deviation MVC';
            app.RDeviationMVCEditField = uieditfield(app.Tab2, 'numeric');
            app.RDeviationMVCEditField.Value = 20;
            app.SubjectNameEditFieldLabel = uilabel(app.Tab2);
            app.SubjectNameEditFieldLabel.Text = 'Subject Name';
            app.SubjectNameEditField = uieditfield(app.Tab2, 'text');
            app.SaveButton = uibutton(app.Tab2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes2 = uiaxes(app.Tab3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.StartRecordingButton = uibutton(app.Tab3, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopRecordingButton = uibutton(app.Tab3, 'push');
            app.StopRecordingButton.Text = 'Stop Recording';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_acquisition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end